classdef Encriptador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GraficarButton          matlab.ui.control.Button
        GuardardatosButton      matlab.ui.control.Button
        ClavePrivadaLabel       matlab.ui.control.Label
        LabelClavePrivada       matlab.ui.control.Label
        ClavePublicaLabel       matlab.ui.control.Label
        LabelClavePublica       matlab.ui.control.Label
        EdadSpinner             matlab.ui.control.Spinner
        EdadSpinnerLabel        matlab.ui.control.Label
        EstaturaLabel           matlab.ui.control.Label
        LabelEstatura           matlab.ui.control.Label
        EdadLabel               matlab.ui.control.Label
        LabelEdad               matlab.ui.control.Label
        PasatiemposLabel        matlab.ui.control.Label
        LabelPasatiempos        matlab.ui.control.Label
        ColorLabel              matlab.ui.control.Label
        LabelColor              matlab.ui.control.Label
        GeneroLabel             matlab.ui.control.Label
        LabelGenero             matlab.ui.control.Label
        NombreLabel             matlab.ui.control.Label
        ColorDropDown           matlab.ui.control.DropDown
        ColorDropDownLabel      matlab.ui.control.Label
        PasatiempoEditField     matlab.ui.control.TextArea
        PasatiempoLabel         matlab.ui.control.Label
        EstaturaEditField       matlab.ui.control.NumericEditField
        EstaturaEditFieldLabel  matlab.ui.control.Label
        GeneroDropDown          matlab.ui.control.DropDown
        GeneroDropDownLabel     matlab.ui.control.Label
        LabelNombre             matlab.ui.control.Label
        DesencriptarButton      matlab.ui.control.Button
        Table                   matlab.ui.control.Table
        EncriptarButton         matlab.ui.control.Button
        NombreEditField         matlab.ui.control.TextArea
        NombreTextAreaLabel     matlab.ui.control.Label
        EstaturaAxe             matlab.ui.control.UIAxes
        ColorAxe                matlab.ui.control.UIAxes
        EdadAxe                 matlab.ui.control.UIAxes
        PasatiemposAxe          matlab.ui.control.UIAxes
        GeneroAxe               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataTable % Tabla de datos
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("Info.xlsx","file")==0
                table = {'Genero', 'Pasatiempos', 'Color', 'Edad', 'Estatura'};
                writecell(table,"Info.xlsx",'sheet',"Datos")
            end
        end

        % Button pushed function: EncriptarButton
        function EncriptarButtonPushed(app, event)
            global p q n k j datos
            [p,q]= primeGenerator(1000);
            [n,k,j] = keyGenerator(p,q);
            clavePublica = [n,k];
            clavePrivada = j;
            nameEncrypted = encrypter(app.NombreEditField.Value{1}, k, n);
            genderEncrypted = encrypter(app.GeneroDropDown.Value, k, n);
            hobbyEncrypted = encrypter(app.PasatiempoEditField.Value{1}, k, n);
            colorEncrypted = encrypter(app.ColorDropDown.Value, k, n);
            ageEncrypted = encrypter(app.EdadSpinner.Value, k, n);
            sizeEncrypted = encrypter(app.EstaturaEditField.Value, k, n);
            
            nL = length(nameEncrypted);
            gL = length(genderEncrypted);
            cL = length(colorEncrypted);
            hL = length(hobbyEncrypted);
            aL = length(ageEncrypted);
            sL = length(sizeEncrypted);
            
            [x1,y1,z1] = greaterThan(nL,gL,hL);
            if x1 == 1
                size = nL;
                genderEncrypted = [genderEncrypted,zeros(1,size-gL)];
                hobbyEncrypted = [hobbyEncrypted,zeros(1,size-hL)];
            elseif y1 == 1
                size = gL;
                nameEncrypted = [nameEncrypted,zeros(1,size-nL)];
                hobbyEncrypted = [hobbyEncrypted,zeros(1,size-hL)];
            elseif z1 == 1
                size = hL;
                nameEncrypted = [nameEncrypted,zeros(1,size-nL)];
                genderEncrypted = [genderEncrypted,zeros(1,size-gL)];
            end
            
            colorEncrypted = [colorEncrypted,zeros(1,size-cL)];
            ageEncrypted = [ageEncrypted,zeros(1,size-aL)];
            sizeEncrypted = [sizeEncrypted,zeros(1,size-sL)];

            datos = [nameEncrypted;genderEncrypted;colorEncrypted;hobbyEncrypted;ageEncrypted;sizeEncrypted];
            disp(datos.')
            app.Table.Data = datos.';
            set(app.LabelClavePublica, "Text", int2str(clavePublica))
            set(app.LabelClavePrivada, "Text", int2str(clavePrivada))
        end

        % Button pushed function: DesencriptarButton
        function DesencriptarButtonPushed(app, event)
            global n j datos
            nameVector = datos(1,:);
            nameVector(nameVector==0) = [];
            
            genderVector = datos(2,:);
            genderVector(genderVector==0) = [];
            
            colorVector = datos(3,:);
            colorVector(colorVector==0) = [];
            
            hobbyVector = datos(4,:);
            hobbyVector(hobbyVector==0) = [];
            
            ageVector = datos(5,:);
            ageVector(ageVector==0) = [];
            
            sizeVector = datos(6,:);
            sizeVector(sizeVector==0) = [];
            
            nameDesencrypted = desencrypter(nameVector, j, n);
            genderDesencrypted = desencrypter(genderVector, j, n);
            colorDesencrypted = desencrypter(colorVector, j, n);
            hobbyDesencrypted = desencrypter(hobbyVector, j, n);
            ageDesencrypted = desencrypter(ageVector, j, n);
            sizeDesencrypted = desencrypter(sizeVector, j, n);
            
            set(app.LabelNombre, "Text", char(nameDesencrypted))
            set(app.LabelGenero, "Text", char(genderDesencrypted))
            set(app.LabelColor, "Text", char(colorDesencrypted))
            set(app.LabelPasatiempos, "Text", char(hobbyDesencrypted))
            set(app.LabelEdad, "Text", int2str(ageDesencrypted))
            set(app.LabelEstatura, "Text", int2str(sizeDesencrypted))
        end

        % Button pushed function: GuardardatosButton
        function GuardardatosButtonPushed(app, event)
%             name = app.NombreEditField.Value{1};
            genderData = app.GeneroDropDown.Value;
            hobbyData = app.PasatiempoEditField.Value{1};
            colorData = app.ColorDropDown.Value;
            ageData = app.EdadSpinner.Value;
            sizeData = app.EstaturaEditField.Value;
            
            tabla = readcell("Info.xlsx");
%             disp(tabla)
            [m,n] = size(tabla);
            
            table = {genderData, hobbyData, colorData, ageData, sizeData};
            tabla(m+1,1:n)=table;
            app.NombreEditField.Value = "";
            app.PasatiempoEditField.Value = "";
            app.LabelClavePublica.Text = "";
            app.LabelClavePrivada.Text = "";
            app.LabelNombre.Text = "";
            app.LabelPasatiempos.Text = "";
            app.LabelEdad.Text = "";
            app.LabelGenero.Text = "";
            app.LabelEstatura.Text = "";
            app.LabelColor.Text = "";
            writecell(tabla,"Info.xlsx", 'sheet', "Datos")
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            tabla = readcell("Info.xlsx");
            [m,] = size(tabla);
            tablaGender = categorical(tabla(2:m,1));
            tablaHobby = categorical(tabla(2:m,2));
            tablaColor = categorical(tabla(2:m,3));
            tablaAge = cell2mat(tabla(2:m,4));
            tablaSize = cell2mat(tabla(2:m,5));
            
            pie(app.GeneroAxe,tablaGender)
            histogram(app.PasatiemposAxe,tablaHobby)
            histogram(app.ColorAxe,tablaColor)
            histogram(app.EdadAxe,tablaAge,10)
            histogram(app.EstaturaAxe,tablaSize,10)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneroAxe = uiaxes(app.UIFigure);
            title(app.GeneroAxe, 'Genero')
            xlabel(app.GeneroAxe, 'X')
            ylabel(app.GeneroAxe, 'Y')
            zlabel(app.GeneroAxe, 'Z')
            app.PasatiemposAxe = uiaxes(app.UIFigure);
            title(app.PasatiemposAxe, 'Pasatiempos')
            xlabel(app.PasatiemposAxe, 'X')
            ylabel(app.PasatiemposAxe, 'Y')
            zlabel(app.PasatiemposAxe, 'Z')
            app.EdadAxe = uiaxes(app.UIFigure);
            title(app.EdadAxe, 'Edad')
            xlabel(app.EdadAxe, 'X')
            ylabel(app.EdadAxe, 'Y')
            zlabel(app.EdadAxe, 'Z')
            app.ColorAxe = uiaxes(app.UIFigure);
            title(app.ColorAxe, 'Color')
            xlabel(app.ColorAxe, 'X')
            ylabel(app.ColorAxe, 'Y')
            zlabel(app.ColorAxe, 'Z')
            app.EstaturaAxe = uiaxes(app.UIFigure);
            title(app.EstaturaAxe, 'Estatura')
            xlabel(app.EstaturaAxe, 'X')
            ylabel(app.EstaturaAxe, 'Y')
            zlabel(app.EstaturaAxe, 'Z')
            app.NombreTextAreaLabel = uilabel(app.UIFigure);
            app.NombreTextAreaLabel.Text = 'Nombre';
            app.NombreEditField = uitextarea(app.UIFigure);
            app.EncriptarButton = uibutton(app.UIFigure, 'push');
            app.EncriptarButton.ButtonPushedFcn = createCallbackFcn(app, @EncriptarButtonPushed, true);
            app.EncriptarButton.Tooltip = {'Click me!'};
            app.EncriptarButton.Text = 'Encriptar';
            app.Table = uitable(app.UIFigure);
            app.Table.ColumnName = {'Nombre'; 'Genero'; 'Color'; 'Pasatiempos'; 'Edad'; 'Estatura'};
            app.Table.RowName = {};
            app.DesencriptarButton = uibutton(app.UIFigure, 'push');
            app.DesencriptarButton.ButtonPushedFcn = createCallbackFcn(app, @DesencriptarButtonPushed, true);
            app.DesencriptarButton.Text = 'Desencriptar';
            app.LabelNombre = uilabel(app.UIFigure);
            app.LabelNombre.Text = '';
            app.GeneroDropDownLabel = uilabel(app.UIFigure);
            app.GeneroDropDownLabel.Text = 'Genero';
            app.GeneroDropDown = uidropdown(app.UIFigure);
            app.GeneroDropDown.Items = {'Masculino', 'Femenino ', 'Otro     '};
            app.GeneroDropDown.Value = 'Masculino';
            app.EstaturaEditFieldLabel = uilabel(app.UIFigure);
            app.EstaturaEditFieldLabel.Text = 'Estatura';
            app.EstaturaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstaturaEditField.Limits = [100 250];
            app.EstaturaEditField.Tooltip = {'Estatura en cm'};
            app.EstaturaEditField.Value = 175;
            app.PasatiempoLabel = uilabel(app.UIFigure);
            app.PasatiempoLabel.Text = 'Pasatiempo';
            app.PasatiempoEditField = uitextarea(app.UIFigure);
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Rojo', 'Azul', 'Amarillo', 'Verde', 'Rosado', 'Negro', 'Blanco', 'Cafe', 'Dorado', 'Morado', 'Otro'};
            app.ColorDropDown.Value = 'Rojo';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Nombre';
            app.LabelGenero = uilabel(app.UIFigure);
            app.LabelGenero.Text = '';
            app.GeneroLabel = uilabel(app.UIFigure);
            app.GeneroLabel.Text = 'Genero';
            app.LabelColor = uilabel(app.UIFigure);
            app.LabelColor.Text = '';
            app.ColorLabel = uilabel(app.UIFigure);
            app.ColorLabel.Text = 'Color';
            app.LabelPasatiempos = uilabel(app.UIFigure);
            app.LabelPasatiempos.Text = '';
            app.PasatiemposLabel = uilabel(app.UIFigure);
            app.PasatiemposLabel.Text = 'Pasatiempos';
            app.LabelEdad = uilabel(app.UIFigure);
            app.LabelEdad.Text = '';
            app.EdadLabel = uilabel(app.UIFigure);
            app.EdadLabel.Text = 'Edad';
            app.LabelEstatura = uilabel(app.UIFigure);
            app.LabelEstatura.Text = '';
            app.EstaturaLabel = uilabel(app.UIFigure);
            app.EstaturaLabel.Text = 'Estatura';
            app.EdadSpinnerLabel = uilabel(app.UIFigure);
            app.EdadSpinnerLabel.Text = 'Edad';
            app.EdadSpinner = uispinner(app.UIFigure);
            app.EdadSpinner.Limits = [12 99];
            app.EdadSpinner.Value = 24;
            app.LabelClavePublica = uilabel(app.UIFigure);
            app.LabelClavePublica.Text = '';
            app.ClavePublicaLabel = uilabel(app.UIFigure);
            app.ClavePublicaLabel.Text = 'Clave Publica';
            app.LabelClavePrivada = uilabel(app.UIFigure);
            app.LabelClavePrivada.Text = '';
            app.ClavePrivadaLabel = uilabel(app.UIFigure);
            app.ClavePrivadaLabel.Text = 'Clave Privada';
            app.GuardardatosButton = uibutton(app.UIFigure, 'push');
            app.GuardardatosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardardatosButtonPushed, true);
            app.GuardardatosButton.Text = 'Guardar datos';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encriptador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end